classdef FaceDetection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MATLABFaceDetectorUIFigure   matlab.ui.Figure
        OnlyUploadimagesintheformatJPGJPEGPNGLabel  matlab.ui.control.Label
        DetectedFacesinPictureLabel  matlab.ui.control.Label
        ManuallyAddedFaces0Label     matlab.ui.control.Label
        DeleteSelectedFrameButton    matlab.ui.control.Button
        AddFrameButton               matlab.ui.control.Button
        EditModeButton               matlab.ui.control.Button
        SaveImageButton              matlab.ui.control.Button
        AutomaticallyDetectedFaces0Label  matlab.ui.control.Label
        UploadImageButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end


   properties (Access = private)
    EditMode = false;   % Flag, Bearbeitungsmodus aktiv
    ManualRectangles = []; % Speichern der Koordinaten manueller Rechtecke.
    AutoDetectedFaces = 0; % Zähler automatisch erkannten Gesichter
   end

    methods (Access = private)
               
function updateFaceCount(app)
 
    app.AutomaticallyDetectedFaces0Label.Text = ['Automatically Detected Faces: ' num2str(app.AutoDetectedFaces)];    % Aktualisierung Label Gesichter
    app.ManuallyAddedFaces0Label.Text = ['Manually Added Faces: ' num2str(size(app.ManualRectangles, 2))];   % Aktualisieren Label manueller Gesichter
end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
   % Dateiauswahl-Dialog
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png', 'All Image Files'; ...
                              '*.*', 'All Files'}, 'Select an Image');
 
   % Nutzerabbruch prüfen.
    if isequal(file, 0)
        disp('User selected Cancel');
    else
       
        selectedImage = fullfile(path, file); %Bildpfad erstellen.

       %Leeren des Array für manuelle Rahmen.
        app.ManualRectangles = []; 
        app.ManuallyAddedFaces0Label.Text = 'Manually Added Faces: 0'; % Setzt Label auf 0

        try
            
             %Bild aus Datei lesen.
            img = imread(selectedImage);

           
            imshow(img, 'Parent', app.UIAxes); %Bild anzeigen

           
            [bboxes] = mtcnn.detectFaces(img); %Gesichtserkennung ausführen

            %Erkannte Gesichter auf dem Bild anzeigen
            if ~isempty(bboxes)
                figure('Visible','off'); 
                imshow(img); % Zeigen des Originalbildes
                hold on; % Bild fixieren für Rahmen.

                %Rahmen für Gesichter zeichnen.
                for ii = 1:size(bboxes, 1) 
                    drawrectangle('Label','','Color','y','Position', bboxes(ii, :), 'FaceAlpha', 0);
                end
                hold(app.UIAxes, 'off'); % Bildfreigabe für Zeichnung.

                % Markierte Gesichter zeigen.
                frame = getframe(gcf);
                annotatedImg = frame.cdata;
                close;
                imshow(annotatedImg, 'Parent', app.UIAxes);
                
                 % Anzahl der Gesichter ermitteln
                numFaces = size(bboxes, 1); 
                app.AutomaticallyDetectedFaces0Label.Text = ['Detected Faces: ' num2str(numFaces)];  %Automatisch erkannte Gesichter aktualisieren.
                
                
            else
                imshow(img, 'Parent', app.UIAxes);
                app.AutomaticallyDetectedFaces0Label.Text = 'Detected Faces: 0'; % Detected Label auf 0 setzen
                
                % Meldung keine Bilder erkannt
                uialert(app.MATLABFaceDetectorUIFigure, 'No faces detected in the image.', 'Face Detection', ...
                        'Icon', 'info');
            end
        catch
            %Meldung Ungültige Datei
            uialert(app.MATLABFaceDetectorUIFigure, 'Invalid or corrupted image file.', 'File Error', ...
                    'Icon', 'error');
        end
    end


        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
       
      % Dateiauswahl-Dialog
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png', 'All Image Files'; ...
                              '*.*', 'All Files'}, 'Select an Image');
     % Nutzerabbruch prüfen.
 if isequal(file, 0)
        disp('User selected Cancel');
    else
        
        
        selectedImage = fullfile(path, file);   %Bildpfad erstellen.

        try
              
            img = imread(selectedImage);  % Bild aus Datei lesen

              
            imshow(img, 'Parent', app.UIAxes);   % Bild anzeigen

             % Gesichtserkennung durchführen
            [bboxes] = mtcnn.detectFaces(img);
             
   %Erkannte Gesichter auf dem Bild anzeigen
            if ~isempty(bboxes)
                imshow(img, 'Parent', app.UIAxes); %Originalbild anzeigen
                hold(app.UIAxes, 'on'); % Bild für Rahmenzeichnung fixieren.
               
                %Rahmen für jedes Gesicht zeichnen.
                for ii = 1:size(bboxes, 1) 
                    rectangle('Position', bboxes(ii, :), 'EdgeColor', 'y', 'LineWidth', 2, 'Parent', app.UIAxes);
                end

                hold(app.UIAxes, 'off'); % Bild für Zeichnungen freigeben.
                
               %"Detected Faces" aktualisieren
                app.AutoDetectedFaces = size(bboxes, 1); % Anzahl erkannter Gesichter speichern.
                updateFaceCount(app); % Aktualiseren "Face Count"
            else
          
                imshow(img, 'Parent', app.UIAxes);
                app.AutomaticallyDetectedFaces0Label.Text = 'Detected Faces: 0'; % Detected Label auf 0 setzen
                
                % Meldung Keine Bilder erkannt
                uialert(app.MATLABFaceDetectorUIFigure, 'No faces detected in the image.', 'Face Detection', ...
                        'Icon', 'info');
            end
            
        catch
            %Meldung Ungültige Datei
            uialert(app.MATLABFaceDetectorUIFigure, 'Invalid or corrupted image file.', 'File Error', ...
                    'Icon', 'error');   
        end
    
end


        end

        % Button pushed function: EditModeButton
        function EditModeButtonPushed(app, event)
  
    % Umschalten zu Edit Mode
    app.EditMode = ~app.EditMode;

    %Edit-Modus-Text aktualisieren.
    if app.EditMode
        app.EditModeButton.Text = 'Deactivate Edit Mode';
    else
        app.EditModeButton.Text = 'Edit Mode';
    end
   
   %Button für Rahmen bearbeiten umschalten.
    updateButtonState(app);
end


function updateButtonState(app)
    if app.EditMode
      
        % EditMode aktiv, Add Frame Knopf aktiviert.
        app.AddFrameButton.Enable = 'on';
        app.DeleteSelectedFrameButton.Enable = 'on';
    else
        %Edit Button inaktiv,Add Frame Knopf deaktivert
        app.AddFrameButton.Enable = 'off';
        app.DeleteSelectedFrameButton.Enable = 'off';
    end
     

        end

        % Button pushed function: AddFrameButton
        function AddFrameButtonPushed(app, event)

    % Bearbeitungsmodus prüfen.
    if app.EditMode
        
        newRect = drawrectangle(app.UIAxes, 'Color', 'yellow'); % Manuellen Rahmen auf UIAxes zeichnen.
        
        % Rahmen zu Manual Rectangles hinzufügen.
        app.ManualRectangles = [app.ManualRectangles, newRect];
        
       
        app.ManuallyAddedFaces0Label.Text = ['Manually Added Faces: ' num2str(size(app.ManualRectangles, 2))]; %Manuelle Rahmen Zähler aktualisieren.
    else
        uialert(app.MATLABFaceDetectorUIFigure, 'Please activate edit mode to add faces manually.', 'Edit Mode Not Active'); % Edit-Modus-Hinweis senden.
    end


        end

        % Close request function: MATLABFaceDetectorUIFigure
        function DeletedFcn(app, event)
            delete(app)
            
        end

        % Button pushed function: DeleteSelectedFrameButton
        function DeleteSelectedFrameButtonPushed(app, event)
    if isempty(app.ManualRectangles)   %  Test manueller Begrenzungsrahmen
        return; 
    end
    
    selectedRectIndex = find(arrayfun(@(x) x.Selected, app.ManualRectangles), 1);

    if ~isempty(selectedRectIndex) % Rahmenauswahl prüfen

        % Selektierter Begrenzungsrahmen entfernen
        delete(app.ManualRectangles(selectedRectIndex)); 
        app.ManualRectangles(selectedRectIndex) = []; 
        
        
        app.ManuallyAddedFaces0Label.Text = ['Manually Added Faces: ' num2str(size(app.ManualRectangles, 2))]; % Anzahl manueller Gesichtern aktualisieren
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MATLABFaceDetectorUIFigure = uifigure('Visible', 'off');
            app.MATLABFaceDetectorUIFigure.Name = 'MATLAB - Face Detector';
            app.MATLABFaceDetectorUIFigure.Icon = fullfile(pathToMLAPP, 'facial-recognition.png');
            app.MATLABFaceDetectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @DeletedFcn, true);
            app.UIAxes = uiaxes(app.MATLABFaceDetectorUIFigure);
            title(app.UIAxes, 'Face Detection')
            app.UploadImageButton = uibutton(app.MATLABFaceDetectorUIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.AutomaticallyDetectedFaces0Label = uilabel(app.MATLABFaceDetectorUIFigure);
            app.AutomaticallyDetectedFaces0Label.Text = 'Automatically Detected Faces: 0 ';
            app.SaveImageButton = uibutton(app.MATLABFaceDetectorUIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.EditModeButton = uibutton(app.MATLABFaceDetectorUIFigure, 'push');
            app.EditModeButton.ButtonPushedFcn = createCallbackFcn(app, @EditModeButtonPushed, true);
            app.EditModeButton.Text = 'Edit Mode';
            app.AddFrameButton = uibutton(app.MATLABFaceDetectorUIFigure, 'push');
            app.AddFrameButton.ButtonPushedFcn = createCallbackFcn(app, @AddFrameButtonPushed, true);
            app.AddFrameButton.Enable = 'off';
            app.AddFrameButton.Text = 'Add Frame';
            app.DeleteSelectedFrameButton = uibutton(app.MATLABFaceDetectorUIFigure, 'push');
            app.DeleteSelectedFrameButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedFrameButtonPushed, true);
            app.DeleteSelectedFrameButton.Enable = 'off';
            app.DeleteSelectedFrameButton.Text = 'Delete Selected Frame';
            app.ManuallyAddedFaces0Label = uilabel(app.MATLABFaceDetectorUIFigure);
            app.ManuallyAddedFaces0Label.Text = 'Manually Added Faces: 0';
            app.DetectedFacesinPictureLabel = uilabel(app.MATLABFaceDetectorUIFigure);
            app.DetectedFacesinPictureLabel.Text = 'Detected Faces in Picture';
            app.OnlyUploadimagesintheformatJPGJPEGPNGLabel = uilabel(app.MATLABFaceDetectorUIFigure);
            app.OnlyUploadimagesintheformatJPGJPEGPNGLabel.Text = {'Only Upload images in the format:'; '*JPG, *JPEG, * PNG'};
            app.ContextMenu = uicontextmenu(app.MATLABFaceDetectorUIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.AutomaticallyDetectedFaces0Label.ContextMenu = app.ContextMenu;
            app.ManuallyAddedFaces0Label.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.MATLABFaceDetectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FaceDetection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MATLABFaceDetectorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MATLABFaceDetectorUIFigure)
        end
    end
end